classdef MonopolyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonopolyMovesUIFigure  matlab.ui.Figure
        BoardImage             matlab.ui.control.Image
        PieceImage             matlab.ui.control.Image
        RollButton             matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        Dice1                  matlab.ui.control.Image
        Dice2                  matlab.ui.control.Image
        PieceListBoxLabel      matlab.ui.control.Label
        PieceListBox           matlab.ui.control.ListBox
    end

    
    properties (Access = private)
         piece_positions = [
           406	25	32	32
           355	25	32	32
           320	25	32	32
           285	25	32	32
           250	25	32	32
           215	25	32	32
           180	25	32	32
           145	25	32	32
           110	25	32	32
           75	25	32	32
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           406	25	32	32];
       
       dice_imgs = ["dice_one.png", "dice_two.png","dice_three.png","dice_four.png","dice_five.png","dice_six.png"];
       
       % Use this to index into position array above
       piece_loc = 0; % start at first square
       piece_idx = 1;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ResetButtonPushed();
            app.PieceListBox.Value = "Dog";
            app.PieceImage.ImageSource = [char(app.PieceListBox.Value), '.png'];
        end

        % Button pushed function: RollButton
        function RollButtonPushed(app, event)
            app.Dice1.Visible = true;
            app.Dice2.Visible = true;
            roll = randi([1 6],1,2);
            app.Dice1.ImageSource = app.dice_imgs(roll(1));
            app.Dice2.ImageSource = app.dice_imgs(roll(2));
            app.piece_loc = mod(app.piece_loc + sum(roll),length(app.piece_positions)-1);
            app.piece_idx = app.piece_loc + 1;
            app.PieceImage.Position = app.piece_positions(app.piece_idx,:);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Dice1.ImageSource = app.dice_imgs(1);
            app.Dice2.ImageSource = app.dice_imgs(1);
            app.Dice1.Visible = false;
            app.Dice2.Visible = false;
            app.piece_loc = 0;
            app.piece_idx = 1;
            app.PieceImage.Position = app.piece_positions(app.piece_idx,:);
        end

        % Value changed function: PieceListBox
        function PieceListBoxValueChanged(app, event)
            app.PieceImage.ImageSource = [char(app.PieceListBox.Value), '.png'];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonopolyMovesUIFigure = uifigure('Visible', 'off');
            app.MonopolyMovesUIFigure.Name = 'Monopoly Moves';
            app.BoardImage = uiimage(app.MonopolyMovesUIFigure);
            app.BoardImage.ImageSource = 'monopoly_board.jpg';
            app.PieceImage = uiimage(app.MonopolyMovesUIFigure);
            app.PieceImage.ImageSource = 'Dog.png';
            app.RollButton = uibutton(app.MonopolyMovesUIFigure, 'push');
            app.RollButton.ButtonPushedFcn = createCallbackFcn(app, @RollButtonPushed, true);
            app.RollButton.Text = 'Roll';
            app.ResetButton = uibutton(app.MonopolyMovesUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Dice1 = uiimage(app.MonopolyMovesUIFigure);
            app.Dice1.ImageSource = 'dice_one.png';
            app.Dice2 = uiimage(app.MonopolyMovesUIFigure);
            app.Dice2.ImageSource = 'dice_one.png';
            app.PieceListBoxLabel = uilabel(app.MonopolyMovesUIFigure);
            app.PieceListBoxLabel.Text = 'Piece';
            app.PieceListBox = uilistbox(app.MonopolyMovesUIFigure);
            app.PieceListBox.Items = {'Car', 'Dog', 'Hat', 'Iron', 'Ship', 'Shoe', 'Thimble', 'Wheelbarrow'};
            app.PieceListBox.ValueChangedFcn = createCallbackFcn(app, @PieceListBoxValueChanged, true);
            app.PieceListBox.Value = 'Car';
            % Show the figure after all components are created
            app.MonopolyMovesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MonopolyApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonopolyMovesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonopolyMovesUIFigure)
        end
    end
end