classdef MatlabFrontend < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        DriveSystemPanel    matlab.ui.container.Panel
        SpeedSliderLabel    matlab.ui.control.Label
        SpeedSlider         matlab.ui.control.Slider
        ForwardButton       matlab.ui.control.Button
        StopButton          matlab.ui.control.Button
        RightButton         matlab.ui.control.Button
        LeftButton          matlab.ui.control.Button
        BackButton          matlab.ui.control.Button
        SpinleftButton      matlab.ui.control.Button
        SpinrightButton     matlab.ui.control.Button
        ServosPanel         matlab.ui.container.Panel
        CameraSliderLabel   matlab.ui.control.Label
        CameraSlider        matlab.ui.control.Slider
        Joint1SliderLabel   matlab.ui.control.Label
        Joint1Slider        matlab.ui.control.Slider
        Joint2SliderLabel   matlab.ui.control.Label
        Joint2Slider        matlab.ui.control.Slider
        Joint3SliderLabel   matlab.ui.control.Label
        Joint3Slider        matlab.ui.control.Slider
        GripperSliderLabel  matlab.ui.control.Label
        GripperSlider       matlab.ui.control.Slider
    end

    
    methods (Access = private)
        
        function sendDriveCmdMultipliers(app,leftMult,rightMult)
            baseSpeed = app.SpeedSlider.Value;
            leftSpeed = baseSpeed * leftMult;
            rightSpeed = baseSpeed * rightMult;
            app.sendDriveCommand(leftSpeed,rightSpeed)
        end
        
        function sendDriveCommand(app,leftSpeed,rightSpeed)
            command = sprintf("motor/go/%d/%d",round(leftSpeed),round(rightSpeed));
            app.fetch(command);
        end
        
        function sendStop(app)
            app.fetch("motor/stop");
        end
        
        function fetch(app, apiParameters)
            import matlab.net.*
            import matlab.net.http.*
            apiPath = "http://eckelsjd-tank.wlan.rose-hulman.edu:3000/api/";
            url = strcat(apiPath,apiParameters);
            resp = send(RequestMessage(),URI(url));
            fprintf("Response: %d\n",resp.StatusCode);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            app.sendDriveCmdMultipliers(1,1);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.sendStop();
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            app.sendDriveCmdMultipliers(1,0.5);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            app.sendDriveCmdMultipliers(0.5,1);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.sendDriveCmdMultipliers(-1,-1);
        end

        % Button pushed function: SpinleftButton
        function SpinleftButtonPushed(app, event)
            app.sendDriveCmdMultipliers(-1,1);
        end

        % Button pushed function: SpinrightButton
        function SpinrightButtonPushed(app, event)
            app.sendDriveCmdMultipliers(1,-1);
        end

        % Value changed function: CameraSlider
        function CameraSliderValueChanged(app, event)
            value = round(app.CameraSlider.Value);
            command = sprintf("servo/camera_pw/%d",value);
            app.fetch(command);
        end

        % Value changed function: GripperSlider
        function GripperSliderValueChanged(app, event)
            value = round(app.GripperSlider.Value);
            command = sprintf("servo/gripper_pw/%d",value);
            app.fetch(command);
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = round(app.Joint1Slider.Value);
            command = sprintf("servo/arm_pw/%d/%d",1,value);
            app.fetch(command);
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = round(app.Joint2Slider.Value);
            command = sprintf("servo/arm_pw/%d/%d",2,value);
            app.fetch(command);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = round(app.Joint3Slider.Value);
            command = sprintf("servo/arm_pw/%d/%d",3,value);
            app.fetch(command);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DriveSystemPanel = uipanel(app.UIFigure);
            app.DriveSystemPanel.Title = 'Drive System';
            app.SpeedSliderLabel = uilabel(app.DriveSystemPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.DriveSystemPanel);
            app.SpeedSlider.MinorTicks = [];
            app.SpeedSlider.Value = 50;
            app.ForwardButton = uibutton(app.DriveSystemPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.StopButton = uibutton(app.DriveSystemPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RightButton = uibutton(app.DriveSystemPanel, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.DriveSystemPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.BackButton = uibutton(app.DriveSystemPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SpinleftButton = uibutton(app.DriveSystemPanel, 'push');
            app.SpinleftButton.ButtonPushedFcn = createCallbackFcn(app, @SpinleftButtonPushed, true);
            app.SpinleftButton.Text = 'Spin left';
            app.SpinrightButton = uibutton(app.DriveSystemPanel, 'push');
            app.SpinrightButton.ButtonPushedFcn = createCallbackFcn(app, @SpinrightButtonPushed, true);
            app.SpinrightButton.Text = 'Spin right';
            app.ServosPanel = uipanel(app.UIFigure);
            app.ServosPanel.Title = 'Servos';
            app.CameraSliderLabel = uilabel(app.ServosPanel);
            app.CameraSliderLabel.Text = 'Camera';
            app.CameraSlider = uislider(app.ServosPanel);
            app.CameraSlider.Limits = [500 2500];
            app.CameraSlider.ValueChangedFcn = createCallbackFcn(app, @CameraSliderValueChanged, true);
            app.CameraSlider.Value = 1600;
            app.Joint1SliderLabel = uilabel(app.ServosPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ServosPanel);
            app.Joint1Slider.Limits = [500 2500];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.Value = 1100;
            app.Joint2SliderLabel = uilabel(app.ServosPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.ServosPanel);
            app.Joint2Slider.Limits = [500 2500];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.Value = 1300;
            app.Joint3SliderLabel = uilabel(app.ServosPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.ServosPanel);
            app.Joint3Slider.Limits = [500 2500];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.Value = 1350;
            app.GripperSliderLabel = uilabel(app.ServosPanel);
            app.GripperSliderLabel.Text = 'Gripper';
            app.GripperSlider = uislider(app.ServosPanel);
            app.GripperSlider.Limits = [500 2500];
            app.GripperSlider.ValueChangedFcn = createCallbackFcn(app, @GripperSliderValueChanged, true);
            app.GripperSlider.Value = 900;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabFrontend

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end