classdef PlateLoaderGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SimPanel               matlab.ui.container.Panel
        Background             matlab.ui.control.Image
        SimulationLabel        matlab.ui.control.Label
        Plate                  matlab.ui.control.Image
        Arms                   matlab.ui.control.Image
        Gripper                matlab.ui.control.Image
        StatusPanel            matlab.ui.container.Panel
        StatusLabel            matlab.ui.control.Label
        ResponseLabel          matlab.ui.control.Label
        GetstatusButton        matlab.ui.control.Button
        DelayPanel             matlab.ui.container.Panel
        TimeDelayLabel         matlab.ui.control.Label
        UITable                matlab.ui.control.Table
        SetvaluesButton        matlab.ui.control.Button
        ResetdefaultButton     matlab.ui.control.Button
        XPanel                 matlab.ui.container.Panel
        XLabel                 matlab.ui.control.Label
        Button1                matlab.ui.control.Button
        Button2                matlab.ui.control.Button
        Button3                matlab.ui.control.Button
        Button4                matlab.ui.control.Button
        Button5                matlab.ui.control.Button
        GripperPanel           matlab.ui.container.Panel
        GripperLabel           matlab.ui.control.Label
        OpenButton             matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
        ZPanel                 matlab.ui.container.Panel
        ZaxisLabel             matlab.ui.control.Label
        ExtendButton           matlab.ui.control.Button
        RetractButton          matlab.ui.control.Button
        ResetPanel             matlab.ui.container.Panel
        ResetButton            matlab.ui.control.Button
        PortEditFieldLabel     matlab.ui.control.Label
        PortEditField          matlab.ui.control.NumericEditField
        ConnectButton          matlab.ui.control.Button
        DisconnectButton       matlab.ui.control.Button
        MovePanel              matlab.ui.container.Panel
        MoveLabel              matlab.ui.control.Label
        FromEditFieldLabel     matlab.ui.control.Label
        FromEditField          matlab.ui.control.NumericEditField
        ToEditFieldLabel       matlab.ui.control.Label
        ToEditField            matlab.ui.control.NumericEditField
        GOButton               matlab.ui.control.Button
        SpecialPanel           matlab.ui.container.Panel
        AnimationsSwitchLabel  matlab.ui.control.Label
        AnimationsSwitch       matlab.ui.control.Switch
    end

    
    properties (Access = private)
        robot
        responseString
        posTable = zeros(2,5,2);
        plateY = 105;
        armY = 160;
        plateX = 3;
        robotConnected = false
        
    end
    
    methods (Access = private)
        
        function results = update(app)
            if (app.robotConnected)
                app.ResponseLabel.Text = app.responseString;
                
                [xPos,zAxis,grip,plate] = app.robot.getProperties();
                
                % If extended
                if (zAxis)
                    yPos = 2;
                    % Draw bars
                    app.Arms.Visible = true;
                    
                else % not extended
                    yPos = 1;  
                    app.Arms.Visible = false;
                end
    
                if (plate)
                    app.Gripper.ImageSource = 'gripper_with_plate.jpg';
                    app.Plate.Visible = false;
                elseif (grip)
                    app.Gripper.ImageSource = 'gripper_closed_no_plate.jpg';
                    app.Plate.Visible = true;
                else
                    app.Gripper.ImageSource = 'gripper_open_no_plate.jpg';
                    app.Plate.Visible = true;
                end
                
                app.Plate.Position(1:2) = [app.posTable(1,app.robot.plateX,1) app.plateY];
                app.Arms.Position(1:2) = [app.posTable(1,xPos,1) app.armY];
                
                if (app.AnimationsSwitch.Value == "On")
                    app.Gripper.Position(2) = app.posTable(yPos,xPos,2);
                    app.animate(app.Gripper,app.Gripper.Position(1), app.posTable(yPos,xPos,1));
                else
                    app.Gripper.Position(1:2) = app.posTable(yPos,xPos,:);
                end
            end

        end
        
        function [] = xButtonPressed(app,location)
            if (app.robotConnected)
                app.responseString = app.robot.x(location);
                app.update();
            end
        end
        
        function [] = animate(app,image,from,to)
            step = sign(to - from);
            x = from:step:to;
            vel = 100; % pixels/s
            
            for i = 1:length(x)
                image.Position(1) = x(i);
                pause(abs(step)/vel)
            end
            
        end
        
        function [] = KeyPressCb(app,hf, event)
            if(app.robotConnected)
                [xPos,zAxis,grip,plate] = app.robot.getProperties()
                if strcmp(event.Key,'rightarrow') == 1
                    app.xButtonPressed(min(xPos + 1,5)); 
                elseif strcmp(event.Key, 'leftarrow') == 1
                    app.xButtonPressed(max(xPos - 1,1));
                elseif strcmp(event.Key, 'uparrow') == 1
                    app.RetractButtonPushed()
                elseif strcmp(event.Key, 'downarrow') == 1
                    app.ExtendButtonPushed()
                elseif strcmp(event.Key,'space') == 1
                    if(grip)
                        app.OpenButtonPushed()
                    else
                        app.CloseButtonPushed()
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            h = app.UIFigure; 
            set(h,'KeyPressFcn',@(h,evt) app.KeyPressCb(h, evt));
            
            app.responseString = " ";
            
            app.UITable.RowName = {'1';'2';'3';'4';'5'};
            
            app.posTable(1,1,:) = [38 240];
            app.posTable(2,1,:) = [38 125];
            app.posTable(1,2,:) = [112 240];
            app.posTable(2,2,:) = [112 125];
            app.posTable(1,3,:) = [186 240];
            app.posTable(2,3,:) = [186 125];
            app.posTable(1,4,:) = [260 240];
            app.posTable(2,4,:) = [260 125];
            app.posTable(1,5,:) = [334 240];
            app.posTable(2,5,:) = [334 125];
                       
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if (app.robotConnected)
                app.responseString = app.robot.reset();
                
                app.update();
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
             if (app.robotConnected)
                app.responseString = app.robot.open();
                app.update();
             end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
             if (app.robotConnected)
                app.responseString = app.robot.close();
                app.update();
             end
        end

        % Button pushed function: ExtendButton
        function ExtendButtonPushed(app, event)
             if (app.robotConnected)
                app.responseString = app.robot.extend();
                app.update();
             end
        end

        % Button pushed function: RetractButton
        function RetractButtonPushed(app, event)
             if (app.robotConnected)
                app.responseString = app.robot.retract();
                app.update();
             end
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            app.xButtonPressed(1);
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            app.xButtonPressed(2);
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            app.xButtonPressed(3);
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            app.xButtonPressed(4);
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            app.xButtonPressed(5);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            if (app.robotConnected)
                from = app.FromEditField.Value;
                to = app.ToEditField.Value;
                app.responseString = app.robot.movePlate(from,to);
                app.update();
            end
        end

        % Button pushed function: GetstatusButton
        function GetstatusButtonPushed(app, event)
            if (app.robotConnected)
                app.responseString = app.robot.getStatus();
                app.update();
            end
        end

        % Button pushed function: SetvaluesButton
        function SetvaluesButtonPushed(app, event)
            if (app.robotConnected)
                app.responseString = app.robot.setTimeValues(app.UITable.Data); 
                app.update();
            end
        end

        % Button pushed function: ResetdefaultButton
        function ResetdefaultButtonPushed(app, event)
            if (app.robotConnected)
                app.responseString = app.robot.resetDefaultTimes();
                app.update();
                
                app.UITable.Data = app.robot.defaultTimeTable;
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if (~app.robotConnected)
                app.robot = PlateLoader(app.PortEditField.Value);
                app.UITable.Data = app.robot.defaultTimeTable;
                app.robotConnected = true;
                app.update();
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            if (app.robotConnected)
                app.robotConnected = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimPanel = uipanel(app.UIFigure);
            app.Background = uiimage(app.SimPanel);
            app.Background.ImageSource = 'robot_background.jpg';
            app.SimulationLabel = uilabel(app.SimPanel);
            app.SimulationLabel.Text = 'Simulation';
            app.Plate = uiimage(app.SimPanel);
            app.Plate.ImageSource = 'plate_only.jpg';
            app.Arms = uiimage(app.SimPanel);
            app.Arms.Visible = 'off';
            app.Arms.ImageSource = 'extended_bars.jpg';
            app.Gripper = uiimage(app.SimPanel);
            app.Gripper.ImageSource = 'gripper_open_no_plate.jpg';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusLabel = uilabel(app.StatusPanel);
            app.StatusLabel.Text = 'Status';
            app.ResponseLabel = uilabel(app.StatusPanel);
            app.ResponseLabel.Text = 'Response';
            app.GetstatusButton = uibutton(app.StatusPanel, 'push');
            app.GetstatusButton.ButtonPushedFcn = createCallbackFcn(app, @GetstatusButtonPushed, true);
            app.GetstatusButton.Text = 'Get status';
            app.DelayPanel = uipanel(app.UIFigure);
            app.TimeDelayLabel = uilabel(app.DelayPanel);
            app.TimeDelayLabel.Text = 'Time Delay';
            app.UITable = uitable(app.DelayPanel);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SetvaluesButton = uibutton(app.DelayPanel, 'push');
            app.SetvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @SetvaluesButtonPushed, true);
            app.SetvaluesButton.Text = 'Set values';
            app.ResetdefaultButton = uibutton(app.DelayPanel, 'push');
            app.ResetdefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResetdefaultButtonPushed, true);
            app.ResetdefaultButton.Text = 'Reset default';
            app.XPanel = uipanel(app.UIFigure);
            app.XLabel = uilabel(app.XPanel);
            app.XLabel.Text = 'X';
            app.Button1 = uibutton(app.XPanel, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '1';
            app.Button2 = uibutton(app.XPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '2';
            app.Button3 = uibutton(app.XPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '3';
            app.Button4 = uibutton(app.XPanel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = '4';
            app.Button5 = uibutton(app.XPanel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = '5';
            app.GripperPanel = uipanel(app.UIFigure);
            app.GripperLabel = uilabel(app.GripperPanel);
            app.GripperLabel.Text = 'Gripper';
            app.OpenButton = uibutton(app.GripperPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.GripperPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ZPanel = uipanel(app.UIFigure);
            app.ZaxisLabel = uilabel(app.ZPanel);
            app.ZaxisLabel.Text = 'Z-axis';
            app.ExtendButton = uibutton(app.ZPanel, 'push');
            app.ExtendButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendButtonPushed, true);
            app.ExtendButton.Text = 'Extend';
            app.RetractButton = uibutton(app.ZPanel, 'push');
            app.RetractButton.ButtonPushedFcn = createCallbackFcn(app, @RetractButtonPushed, true);
            app.RetractButton.Text = 'Retract';
            app.ResetPanel = uipanel(app.UIFigure);
            app.ResetButton = uibutton(app.ResetPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PortEditFieldLabel = uilabel(app.ResetPanel);
            app.PortEditFieldLabel.Text = 'Port:';
            app.PortEditField = uieditfield(app.ResetPanel, 'numeric');
            app.PortEditField.Limits = [1 11];
            app.PortEditField.RoundFractionalValues = 'on';
            app.PortEditField.ValueDisplayFormat = '%6d';
            app.PortEditField.Value = 7;
            app.ConnectButton = uibutton(app.ResetPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ResetPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.MovePanel = uipanel(app.UIFigure);
            app.MoveLabel = uilabel(app.MovePanel);
            app.MoveLabel.Text = 'Move';
            app.FromEditFieldLabel = uilabel(app.MovePanel);
            app.FromEditFieldLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.MovePanel, 'numeric');
            app.FromEditField.Limits = [1 5];
            app.FromEditField.RoundFractionalValues = 'on';
            app.FromEditField.ValueDisplayFormat = '%6d';
            app.FromEditField.Value = 1;
            app.ToEditFieldLabel = uilabel(app.MovePanel);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.MovePanel, 'numeric');
            app.ToEditField.Limits = [1 5];
            app.ToEditField.RoundFractionalValues = 'on';
            app.ToEditField.ValueDisplayFormat = '%6d';
            app.ToEditField.Value = 1;
            app.GOButton = uibutton(app.MovePanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.SpecialPanel = uipanel(app.UIFigure);
            app.AnimationsSwitchLabel = uilabel(app.SpecialPanel);
            app.AnimationsSwitchLabel.Text = 'Animations';
            app.AnimationsSwitch = uiswitch(app.SpecialPanel, 'slider');
            app.AnimationsSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlateLoaderGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end